classdef userControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PositionControlAppUIFigure      matlab.ui.Figure
        ElectricMotorControlPanelLabel  matlab.ui.control.Label
        LaunchApplicationonTargetPanel  matlab.ui.container.Panel
        ConnecttoTargetButton           matlab.ui.control.Button
        BuildSimulinkModelButton        matlab.ui.control.Button
        DownloadApplicationButton       matlab.ui.control.Button
        StartApplicationButton          matlab.ui.control.Button
        StopApplicationButton           matlab.ui.control.Button
        OpenModelButton                 matlab.ui.control.Button
        VariantDropDownLabel            matlab.ui.control.Label
        VariantDropDown                 matlab.ui.control.DropDown
        ElectricMotorControlPanel       matlab.ui.container.Panel
        MotormodeLampLabel              matlab.ui.control.Label
        MotormodeLamp                   matlab.ui.control.Lamp
        MotorStatusSwitchLabel          matlab.ui.control.Label
        MotorStatusSwitch               matlab.ui.control.ToggleSwitch
        VelocityCommandKnobLabel        matlab.ui.control.Label
        VelocityCommandKnob             matlab.ui.control.Knob
        CommandTypeKnobLabel            matlab.ui.control.Label
        CommandTypeKnob                 matlab.ui.control.DiscreteKnob
        ViewTargetScreenButton          matlab.ui.control.Button
        OpenSDIButton                   matlab.ui.control.Button
        RealTimeVisualizationPanel      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RotorSpeedradsGaugeLabel        matlab.ui.control.Label
        RotorSpeedradsGauge             matlab.ui.control.SemicircularGauge
        TargetStatusPanel               matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        StatusEditField                 matlab.ui.control.EditField
        StopTimeEditFieldLabel          matlab.ui.control.Label
        StopTimeEditField               matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        SampleTimeEditFieldLabel        matlab.ui.control.Label
        SampleTimeEditField             matlab.ui.control.NumericEditField
        ExitButton                      matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        modelName
        slp = currentProject;                      % ProjectManager
        hInst;                                      % Instrumentation for real-time visualization
        slrtReady
        timerObj
        tSpan
    end
    
    properties (Access = public)
        tg                                          % target object
        targetSettings                              % Default target settings
        isStandalone = isdeployed();
    end
    
    methods (Access = private)
        
        function closeApp(app)
            answer = questdlg('Are you sure you wish to exit?', 'Exit choice', 'Yes','No','No');
            % Handle response
            switch answer
                case 'Yes'
                    app.tg = slrt;
                    if ~strcmp(app.tg.Connected,'No')
                        if ~(strcmp(app.tg.Application,'loader'))
                            if ~(isempty(app.hInst))
                                app.hInst.stop;
                            end
                            app.tg.stop;
                        end
                    end
                    
                    bdclose all;
                    
                    if ~(isempty(app.hInst))
                        delete(app.hInst)
                    end
                    delete(app);
                    
                case 'No'
            end
        end
        
        
        function disableButtons(app)
            app.OpenModelButton.Enable = 'off';
            app.ConnecttoTargetButton.Enable = 'off';
            app.BuildSimulinkModelButton.Enable = 'off';
            app.DownloadApplicationButton.Enable = 'off';
            app.StartApplicationButton.Enable = 'off';
            app.StopApplicationButton.Enable = 'off';
            app.ExitButton.Enable = 'off';
            app.ViewTargetScreenButton.Enable = 'off';
            app.OpenSDIButton.Enable = 'off';
            app.VariantDropDown.Enable = 'off';
            drawnow;
        end
        
        
        function enableButtons(app)
            app.ConnecttoTargetButton.Enable = 'on';
            if ~app.isStandalone
                app.OpenModelButton.Enable = 'on';
                app.BuildSimulinkModelButton.Enable = 'on';
            end
            app.DownloadApplicationButton.Enable = 'on';
            app.StartApplicationButton.Enable = 'on';
            app.StopApplicationButton.Enable = 'on';
            app.ExitButton.Enable = 'on';
            app.ViewTargetScreenButton.Enable = 'on';
            app.OpenSDIButton.Enable = 'on';
            app.VariantDropDown.Enable = 'on';
            drawnow;
        end
        
        
        function setupInstrumentation(app)
            % Parameters
            app.tSpan = 5;
            resFactor = 2;
            maxNumPoints = floor(resFactor*app.tSpan/app.tg.SampleTime);
            
            % create the instrumentation object
            app.hInst = SimulinkRealTime.prototype.Instrumentation(app.modelName);
            
            if contains(app.modelName,'hil','IgnoreCase',true)
                velCommandSigPath = [app.modelName,'/TI C2000/Speedgoat_outputs/Signal Copy'];
                rotVelSigPath = [app.modelName,'/TI C2000/Speedgoat_outputs/Signal Copy1'];
                modeSigPath = [app.modelName,'/TI C2000/Speedgoat_inputs/Constant'];
            elseif contains(app.modelName,'rcp','IgnoreCase',true)
                velCommandSigPath = [app.modelName,'/ManualTestInputs/velocityCmd'];
                rotVelSigPath = [app.modelName,'/log/rotorVelocityCopy'];
                modeSigPath = [app.modelName,'/log/modeCopy'];
            else
                errordlg('No intrumentation objects configured for model ''%s''.',app.modelName);
            end
            
            % connect signals to scalar displays
            app.hInst.connectScalar(app.RotorSpeedradsGauge,rotVelSigPath,1, 'HistoryFlag',1);
            %app.hInst.connectScalar(app.MotormodeLamp,modeSigPath,1,'PropertyName','Color','Callback', @setMotorLampColor);
            
            % connect signals to the axes
            app.hInst.connectLine(app.UIAxes, velCommandSigPath, 1, 'MaximumNumPoints',maxNumPoints);
            app.hInst.connectLine(app.UIAxes, rotVelSigPath,     1, 'MaximumNumPoints',maxNumPoints);
            
            % add a legend
            legend(app.UIAxes,{'Command','Actual'});
            
            % add a callback to wrap the x axis
            app.hInst.connectCallback(@app.updateUIAxes);
            
            % Define axis
            app.UIAxes.XLim   = [0 app.tSpan];
            app.UIAxes.XTick  = 0:app.tSpan;
            app.UIAxes.YLim   = [-200 200];
            app.UIAxes.XTickLabel = strsplit(num2str(0:app.tSpan));
        end
        
        
        function updateUIAxes(app,~,eventData)
            currTime = eventData.ExecTime;
            
            % update XLim so that the axis wrap
            if max(currTime)>=max(app.UIAxes.XLim)
                app.UIAxes.XTick      = app.UIAxes.XTick         + app.tSpan;
                app.UIAxes.XLim       = max(app.UIAxes.XLim)     + [0 app.tSpan];
                app.UIAxes.XTickLabel = strsplit(num2str(app.UIAxes.XTick));
            end
        end
        
        
        function enableAppControls(app)
           % app.MotormodeLamp.Enable = 'on';
           % app.MotormodeLampLabel.Enable = 'on';
            app.MotorStatusSwitch.Enable = 'on';
            app.MotorStatusSwitchLabel.Enable = 'on';
            if app.tg.getparam('ManualTestInputs/MotorOn','Value') == 1
                app.MotorStatusSwitch.Value = 'On';
            else
                app.MotorStatusSwitch.Value = 'Off';
            end
            app.VelocityCommandKnob.Enable = 'on';
            app.VelocityCommandKnobLabel.Enable = 'on';
            app.VelocityCommandKnob.Value = app.tg.getparam('ManualTestInputs/velocityCmd','Value');
            app.CommandTypeKnob.Enable = 'on';
            app.CommandTypeKnobLabel.Enable = 'on';
            switch app.tg.getparam('ManualTestInputs/commandType','Value')
                case 0
                    app.CommandTypeKnob.Value = 'Error';
                case 1
                    app.CommandTypeKnob.Value = 'Open Loop';
                case 2
                    app.CommandTypeKnob.Value = 'Calibrate Encoder';
                case 3
                    app.CommandTypeKnob.Value = 'Closed Loop';
                otherwise
                    errordlg('Error getting the command type from the target.');
            end
            app.OpenSDIButton.Enable = 'on';
            
            % Enable displays
            app.StopTimeEditField.Enable = 'on';
            app.StopTimeEditFieldLabel.Enable = 'on';
            app.StopTimeEditField.Value = app.tg.StopTime;
            app.StopTimeEditField.Editable = 'on';
            app.SimulationTimeEditField.Enable = 'on';
            app.SimulationTimeEditFieldLabel.Enable = 'on';
            app.SimulationTimeEditField.Value = 0;
            app.SampleTimeEditField.Enable = 'on';
            app.SampleTimeEditFieldLabel.Enable = 'on';
            app.SampleTimeEditField.Value = app.tg.SampleTime;
        end
        
        function prepAppTarget(app)
         
            load_system(app.modelName);
            
            if contains(app.modelName,'hil','IgnoreCase',true)
                switchConfig('HIL');
            elseif contains(app.modelName,'rcp','IgnoreCase',true)
                switchConfig('RCP');
            else
                errordlg('No configuration set found for model ''%s''.',app.modelName);
            end
            
            set_param([app.modelName,'/enManualTestInputs'],'Value','1');
            
            if app.slrtReady
                if isfile(fullfile(app.slp.SimulinkCodeGenFolder,[app.modelName,'.mldatx']))
                    app.DownloadApplicationButton.Enable = 'on';
                    
                    if (strcmpi(app.tg.Application,app.modelName))
                        app.StartApplicationButton.Enable = 'on';
                        enableAppControls(app);
                    else
                        app.StartApplicationButton.Enable = 'off';
                    end
                else
                    app.DownloadApplicationButton.Enable = 'off';
                end
            else
                app.ConnecttoTargetButton.Enable = 'on';
            end
            
            if ~app.isStandalone
                app.BuildSimulinkModelButton.Enable = 'on';
                app.OpenModelButton.Enable = 'on';
            else
                app.BuildSimulinkModelButton.Enable = 'off';
                app.OpenModelButton.Enable = 'off';
            end
            
        end
        
        function color = setMotorLampColor(~,d)
            switch uint8(d)
                case 6
                    color = 'red';
                case 5
                    color = 'green';
                case 4
                    color = 'yellow';
                case 3
                    color = 'cyan';
                case 2
                    color = 'magenta';
                case 1
                    color = 'red';
                otherwise
                    color = 'white';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.slrtReady = false();
            app.StatusEditField.Value = 'Starting app...';
            disableButtons(app);
            
            % go to work folder
            cd(app.slp.ProjectStartupFolder);
            
            % Set model name
            app.modelName = app.VariantDropDown.Value;
            
            % Create timer
            app.timerObj = timer('TimerFcn', {@updateExecTime, app},...
                'StartFcn',  {@startSimulation, app},...
                'StopFcn',  {@stopSimulation, app},...
                'Period', 0.02, 'ExecutionMode','fixedSpacing','BusyMode','drop');
            
            % Update GUI
            app.StatusEditField.Value = 'Ready';
            app.Lamp.Color = 'g';
            app.ConnecttoTargetButton.Enable = 'on';
            app.ExitButton.Enable = 'on';
            app.VariantDropDown.Enable = 'on';
            if ~app.isStandalone
                app.OpenModelButton.Enable = 'on';
                app.BuildSimulinkModelButton.Enable = 'on';
            end
            
            function updateExecTime(~, ~, app)
                app.SimulationTimeEditField.Value = app.tg.ExecTime;
                if strcmp(app.tg.Status,'stopped')
                    stop(app.timerObj);
                end
            end
            
            function startSimulation(~, ~, app)
                app.StatusEditField.Value = 'Starting Application...';
                app.Lamp.Color = 'y';
                disableButtons(app);            
               
                % set up instrumentation for real-time signal visualization
                if ~(isempty(app.hInst))
                    clearData(app.hInst);
                end
                app.setupInstrumentation;
                              
                % Start instrumentation and target
                app.hInst.start(app.tg);
                
                app.StatusEditField.Value = 'Application running';
                app.Lamp.Color = 'y';
                enableButtons(app);
                app.ConnecttoTargetButton.Enable = 'off';
                app.StartApplicationButton.Enable = 'off';
            end
            
            function stopSimulation(~, ~, app)
                if strcmp(app.tg.Status,'stopped')
                    app.StatusEditField.Value = 'Application finished';
                else
                    app.StatusEditField.Value = 'Stopping Application...';
                    disableButtons(app);
                    
                    % Stop instrumentation and target
                    if ~(isempty(app.hInst))
                        app.hInst.stop;
                    end
                    app.tg.stop;
                    
                    app.StatusEditField.Value = 'Application stopped';
                end
                
                % Update buttons status
                enableButtons(app);
                app.StopApplicationButton.Enable = 'off';
                app.ConnecttoTargetButton.Enable = 'off';
            end
            
        end

        % Button pushed function: ConnecttoTargetButton
        function ConnecttoTargetButtonPushed(app, event)
            app.StatusEditField.Value = 'Connecting to target...';
            disableButtons(app);
            
            % Get default target settings
            app.targetSettings = SimulinkRealTime.getTargetSettings;
            
            % Check that IP is reachable via system ping
            try
                [~,echo]=dos(['ping ',app.targetSettings.TcpIpTargetAddress]);
            catch  pingerror
                errordlg( pingerror.message );
                app.ConnecttoTargetButton.Enable = 'on';
            end
            if contains(echo,'The name specified is not recognized')
                app.StatusEditField.Value = 'Error: ping system command not found';
                app.Lamp.Color = 'r';
                return;
            else
                if ~contains(echo,'TTL=')
                    app.StatusEditField.Value = 'Error: no ping response at defined target IP address';
                    app.Lamp.Color = 'r';
                    app.ConnecttoTargetButton.Enable = 'on';
                    return;
                end
            end
            
            % Create target object for default target settings
            app.tg = slrt(app.targetSettings);
            
            % Connect to target
            try
                if strcmp(app.tg.ping('reset'), 'success')
                    app.StatusEditField.Value = 'Target connection established';
                    app.Lamp.Color = 'g';
                    app.slrtReady = true();
                    
                    prepAppTarget(app);
                    
                    app.ViewTargetScreenButton.Enable = true();
                    app.ExitButton.Enable = 'on';
                    app.VariantDropDown.Enable = 'on';
                    app.OpenModelButton.Enable = 'on';
                    
                elseif strcmp(app.tg.ping('reset'), 'failed')
                    app.StatusEditField.Value = 'Error: Target connection could not be established. Please check that kernel version on Speedgoat matches MATLAB release.';
                    app.Lamp.Color = 'r';
                else
                    app.StatusEditField.Value = 'Error: Target connection could not be established';
                    app.Lamp.Color = 'r';
                end
            catch E
                app.StatusEditField.Value = 'Error: Unknown error while trying to connect to target';
                app.Lamp.Color = 'r';
                errordlg(E.message);
            end
            
        end

        % Button pushed function: BuildSimulinkModelButton
        function BuildSimulinkModelButtonPushed(app, event)
            app.StatusEditField.Value = 'Building application...';
            disableButtons(app);
            try
                rtwbuild(app.modelName);
                
                app.StatusEditField.Value = 'Application built';
                app.Lamp.Color = 'g';
            catch E
                app.StatusEditField.Value = 'Error building model.';
                app.BuildSimulinkModelButton.Enable = 'on';
                app.Lamp.Color = 'r';
                errordlg(E.message);
            end
            
            app.prepAppTarget();
            app.ExitButton.Enable = 'on';
            
        end

        % Button pushed function: DownloadApplicationButton
        function DownloadApplicationButtonPushed(app, event)
            app.StatusEditField.Value = 'Loading application...';
            disableButtons(app);                                 % custom private function
            
            try
                load(app.tg,fullfile(app.slp.SimulinkCodeGenFolder,app.modelName)); % download
                
                app.StatusEditField.Value = 'Application loaded';
                app.ConnecttoTargetButton.Enable = 'off';
            catch E
                app.StatusEditField.Value = 'Error loading application on target.';
                app.Lamp.Color = 'r';
                errordlg(E.message);
            end
            
            enableButtons(app);
            app.StopApplicationButton.Enable = 'off';
            
            prepAppTarget(app);
        end

        % Button pushed function: StartApplicationButton
        function StartApplicationButtonPushed(app, event)
            try
                start(app.timerObj);
            catch E
                app.StatusEditField.Value = 'Error: Application could not be started';
                app.Lamp.Color = 'r';
                errordlg(E.message);
            end
        end

        % Button pushed function: StopApplicationButton
        function StopApplicationButtonPushed(app, event)
            try
                stop(app.timerObj);
            catch E
                app.StatusEditField.Value = 'Error: Application could not be stopped';
                app.Lamp.Color = 'r';
                errordlg(E.message);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closeApp(app);                                         % custom private function
        end

        % Close request function: PositionControlAppUIFigure
        function PositionControlAppUIFigureCloseRequest(app, event)
            closeApp(app);                                          % custom private function
        end

        % Button pushed function: ViewTargetScreenButton
        function ViewTargetScreenButtonPushed(app, event)
            app.tg.viewTargetScreen;
        end

        % Button pushed function: OpenSDIButton
        function OpenSDIButtonPushed(app, event)
            Simulink.sdi.view;
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            app.StatusEditField.Value = 'Opening Simulink model...';
            app.Lamp.Color = 'y';
            open_system(app.modelName);
            app.Lamp.Color = 'g';
            app.StatusEditField.Value = 'Ready';
        end

        % Value changed function: VariantDropDown
        function VariantDropDownValueChanged(app, event)
            app.modelName = event.Value;
            prepAppTarget(app);
        end

        % Value changed function: MotorStatusSwitch
        function MotorStatusSwitchValueChanged(app, event)
            if strcmpi(event.Value,'on')
                app.tg.setparam('ManualTestInputs/MotorOn','Value',1);
            else
                app.tg.setparam('ManualTestInputs/MotorOn','Value',0);
            end
        end

        % Value changing function: VelocityCommandKnob
        function VelocityCommandKnobValueChanging(app, event)
            app.tg.setparam('ManualTestInputs/velocityCmd','Value',event.Value);
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            app.tg.StopTime = event.Value;
        end

        % Value changed function: CommandTypeKnob
        function CommandTypeKnobValueChanged(app, event)
            switch event.Value
                case 'Error'
                    app.tg.setparam('ManualTestInputs/commandType','Value',0);
                case 'Open Loop'
                    app.tg.setparam('Manual_TestInputs/commandType','Value',1);
                case 'Calibrate Encoder'
                    app.tg.setparam('ManualTestInputs/commandType','Value',2);
                case 'Closed Loop'
                    app.tg.setparam('ManualTestInputs/commandType','Value',3);
                otherwise
                    errordlg(sprintf('%s is unknown.',event.Value));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PositionControlAppUIFigure = uifigure('Visible', 'off');
            app.PositionControlAppUIFigure.Name = 'Position Control App';
            app.PositionControlAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @PositionControlAppUIFigureCloseRequest, true);
            app.ElectricMotorControlPanelLabel = uilabel(app.PositionControlAppUIFigure);
            app.ElectricMotorControlPanelLabel.Text = 'Electric Motor Control Panel';
            app.LaunchApplicationonTargetPanel = uipanel(app.PositionControlAppUIFigure);
            app.LaunchApplicationonTargetPanel.Title = 'Launch Application on Target';
            app.ConnecttoTargetButton = uibutton(app.LaunchApplicationonTargetPanel, 'push');
            app.ConnecttoTargetButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoTargetButtonPushed, true);
            app.ConnecttoTargetButton.Icon = 'connect.PNG';
            app.ConnecttoTargetButton.Tooltip = {'"Left-click to connect to Target"'};
            app.ConnecttoTargetButton.Text = 'Connect to Target';
            app.BuildSimulinkModelButton = uibutton(app.LaunchApplicationonTargetPanel, 'push');
            app.BuildSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildSimulinkModelButtonPushed, true);
            app.BuildSimulinkModelButton.Icon = 'build.PNG';
            app.BuildSimulinkModelButton.Tooltip = {'"Left-click to build the model"'};
            app.BuildSimulinkModelButton.Text = 'Build Simulink Model';
            app.DownloadApplicationButton = uibutton(app.LaunchApplicationonTargetPanel, 'push');
            app.DownloadApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadApplicationButtonPushed, true);
            app.DownloadApplicationButton.Icon = 'deploy.PNG';
            app.DownloadApplicationButton.Tooltip = {'"Left-click to download the application"'};
            app.DownloadApplicationButton.Text = 'Download Application';
            app.StartApplicationButton = uibutton(app.LaunchApplicationonTargetPanel, 'push');
            app.StartApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @StartApplicationButtonPushed, true);
            app.StartApplicationButton.Icon = 'run.png';
            app.StartApplicationButton.Tooltip = {'"Left-click to start the application"'};
            app.StartApplicationButton.Text = 'Start Application';
            app.StopApplicationButton = uibutton(app.LaunchApplicationonTargetPanel, 'push');
            app.StopApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @StopApplicationButtonPushed, true);
            app.StopApplicationButton.Icon = 'stop.png';
            app.StopApplicationButton.Tooltip = {'"Left-click to stop the application"'};
            app.StopApplicationButton.Text = 'Stop Application';
            app.OpenModelButton = uibutton(app.LaunchApplicationonTargetPanel, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Tooltip = {'Open model to be built and executed in real-time on target.'};
            app.OpenModelButton.Text = 'Open Model';
            app.VariantDropDownLabel = uilabel(app.LaunchApplicationonTargetPanel);
            app.VariantDropDownLabel.Text = 'Variant';
            app.VariantDropDown = uidropdown(app.LaunchApplicationonTargetPanel);
            app.VariantDropDown.Items = {'RCP'};
            app.VariantDropDown.ItemsData = {'FieldOrientedController_RCP'};
            app.VariantDropDown.ValueChangedFcn = createCallbackFcn(app, @VariantDropDownValueChanged, true);
            app.VariantDropDown.Value = 'FieldOrientedController_RCP';
            app.ElectricMotorControlPanel = uipanel(app.PositionControlAppUIFigure);
            app.ElectricMotorControlPanel.Title = 'Electric Motor Control';
            app.MotormodeLampLabel = uilabel(app.ElectricMotorControlPanel);
            app.MotormodeLampLabel.Enable = 'off';
            app.MotormodeLampLabel.Visible = 'off';
            app.MotormodeLampLabel.Text = 'Motor mode';
            app.MotormodeLamp = uilamp(app.ElectricMotorControlPanel);
            app.MotormodeLamp.Enable = 'off';
            app.MotormodeLamp.Visible = 'off';
            app.MotorStatusSwitchLabel = uilabel(app.ElectricMotorControlPanel);
            app.MotorStatusSwitchLabel.Enable = 'off';
            app.MotorStatusSwitchLabel.Text = 'Motor Status';
            app.MotorStatusSwitch = uiswitch(app.ElectricMotorControlPanel, 'toggle');
            app.MotorStatusSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorStatusSwitchValueChanged, true);
            app.MotorStatusSwitch.Enable = 'off';
            app.VelocityCommandKnobLabel = uilabel(app.ElectricMotorControlPanel);
            app.VelocityCommandKnobLabel.Enable = 'off';
            app.VelocityCommandKnobLabel.Text = 'Velocity Command';
            app.VelocityCommandKnob = uiknob(app.ElectricMotorControlPanel, 'continuous');
            app.VelocityCommandKnob.Limits = [-200 200];
            app.VelocityCommandKnob.ValueChangingFcn = createCallbackFcn(app, @VelocityCommandKnobValueChanging, true);
            app.VelocityCommandKnob.Enable = 'off';
            app.CommandTypeKnobLabel = uilabel(app.ElectricMotorControlPanel);
            app.CommandTypeKnobLabel.Enable = 'off';
            app.CommandTypeKnobLabel.Text = 'Command Type';
            app.CommandTypeKnob = uiknob(app.ElectricMotorControlPanel, 'discrete');
            app.CommandTypeKnob.Items = {'Error', 'Open Loop', 'Calibrate Encoder', 'Closed Loop'};
            app.CommandTypeKnob.ValueChangedFcn = createCallbackFcn(app, @CommandTypeKnobValueChanged, true);
            app.CommandTypeKnob.Enable = 'off';
            app.CommandTypeKnob.Value = 'Error';
            app.ViewTargetScreenButton = uibutton(app.PositionControlAppUIFigure, 'push');
            app.ViewTargetScreenButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTargetScreenButtonPushed, true);
            app.ViewTargetScreenButton.Icon = 'tet.PNG';
            app.ViewTargetScreenButton.Text = 'View Target Screen';
            app.OpenSDIButton = uibutton(app.PositionControlAppUIFigure, 'push');
            app.OpenSDIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSDIButtonPushed, true);
            app.OpenSDIButton.Icon = 'data.PNG';
            app.OpenSDIButton.Text = 'Open SDI';
            app.RealTimeVisualizationPanel = uipanel(app.PositionControlAppUIFigure);
            app.RealTimeVisualizationPanel.Title = 'Real-Time Visualization';
            app.UIAxes = uiaxes(app.RealTimeVisualizationPanel);
            title(app.UIAxes, 'Speed')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, '[rad/s]')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-1.5 1.5];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = '';
            app.RotorSpeedradsGaugeLabel = uilabel(app.RealTimeVisualizationPanel);
            app.RotorSpeedradsGaugeLabel.Text = 'Rotor Speed [rad/s]';
            app.RotorSpeedradsGauge = uigauge(app.RealTimeVisualizationPanel, 'semicircular');
            app.RotorSpeedradsGauge.Limits = [-200 200];
            app.TargetStatusPanel = uipanel(app.PositionControlAppUIFigure);
            app.TargetStatusPanel.Title = 'Target Status';
            app.Lamp = uilamp(app.TargetStatusPanel);
            app.StatusEditField = uieditfield(app.TargetStatusPanel, 'text');
            app.StatusEditField.Editable = 'off';
            app.StopTimeEditFieldLabel = uilabel(app.TargetStatusPanel);
            app.StopTimeEditFieldLabel.Enable = 'off';
            app.StopTimeEditFieldLabel.Text = 'Stop Time:';
            app.StopTimeEditField = uieditfield(app.TargetStatusPanel, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Editable = 'off';
            app.StopTimeEditField.Enable = 'off';
            app.SimulationTimeEditFieldLabel = uilabel(app.TargetStatusPanel);
            app.SimulationTimeEditFieldLabel.Enable = 'off';
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time:';
            app.SimulationTimeEditField = uieditfield(app.TargetStatusPanel, 'numeric');
            app.SimulationTimeEditField.Editable = 'off';
            app.SimulationTimeEditField.Enable = 'off';
            app.SampleTimeEditFieldLabel = uilabel(app.TargetStatusPanel);
            app.SampleTimeEditFieldLabel.Enable = 'off';
            app.SampleTimeEditFieldLabel.Text = 'Sample Time:';
            app.SampleTimeEditField = uieditfield(app.TargetStatusPanel, 'numeric');
            app.SampleTimeEditField.Editable = 'off';
            app.SampleTimeEditField.Enable = 'off';
            app.ExitButton = uibutton(app.PositionControlAppUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'exit.PNG';
            app.ExitButton.Tooltip = {'"Left-click to exit"'};
            app.ExitButton.Text = 'Exit';
            app.Image_2 = uiimage(app.PositionControlAppUIFigure);
            app.Image_2.ImageSource = 'Speedgoat.png';
            app.Image_3 = uiimage(app.PositionControlAppUIFigure);
            app.Image_3.ImageSource = 'Mathworks.png';
            % Show the figure after all components are created
            app.PositionControlAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PositionControlAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PositionControlAppUIFigure)
        end
    end
end